classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        V7ImportdataC1TC2HC4RButton  matlab.ui.control.Button
        cqinEditField                matlab.ui.control.EditField
        ckns05EditField              matlab.ui.control.EditField
        GammaEditField               matlab.ui.control.EditField
        KappaEditField               matlab.ui.control.EditField
        LambdaEditField              matlab.ui.control.EditField
        HbEditField                  matlab.ui.control.EditField
        DiameterEditField            matlab.ui.control.EditField
        CloseTheAppButton            matlab.ui.control.Button
        ShowsourceButton             matlab.ui.control.Button
        SourceEditField              matlab.ui.control.EditField
        Importdatasinglefiletimeflowtwofiles12Button  matlab.ui.control.Button
        KappaEditFieldLabel          matlab.ui.control.Label
        cqinEditFieldLabel           matlab.ui.control.Label
        DiameterEditFieldLabel       matlab.ui.control.Label
        cknss05EditFieldLabel        matlab.ui.control.Label
        HbEditFieldLabel             matlab.ui.control.Label
        GammaEditFieldLabel          matlab.ui.control.Label
        LambdaEditFieldLabel         matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        WaterlevelandrainTab         matlab.ui.container.Tab
        RainUIAxes                   matlab.ui.control.UIAxes
        HeightUIAxes                 matlab.ui.control.UIAxes
        LambdacalculationTab         matlab.ui.container.Tab
        CalculateLambdaButton        matlab.ui.control.Button
        T2EditField                  matlab.ui.control.EditField
        T1EditField                  matlab.ui.control.EditField
        GetT1T2Button                matlab.ui.control.Button
        T2EditFieldLabel             matlab.ui.control.Label
        T1EditFieldLabel             matlab.ui.control.Label
        Height2UIAxes                matlab.ui.control.UIAxes
        Dhdt2UIAxes                  matlab.ui.control.UIAxes
        GammacalculationTab          matlab.ui.container.Tab
        CalculateGammaButton         matlab.ui.control.Button
        H_dry_highEditField          matlab.ui.control.EditField
        H_dry_lowEditField           matlab.ui.control.EditField
        EstimateH_minButton          matlab.ui.control.Button
        T4EditField                  matlab.ui.control.EditField
        T3EditField                  matlab.ui.control.EditField
        GetT3T4Button                matlab.ui.control.Button
        H_dry_highEditFieldLabel     matlab.ui.control.Label
        H_dry_lowEditFieldLabel      matlab.ui.control.Label
        T3Label                      matlab.ui.control.Label
        T4EditFieldLabel             matlab.ui.control.Label
        Rain3UIAxes                  matlab.ui.control.UIAxes
        Height3UIAxes                matlab.ui.control.UIAxes
        Dhdt3UIAxes                  matlab.ui.control.UIAxes
        PredictionTab                matlab.ui.container.Tab
        PredictheightButton          matlab.ui.control.Button
        T6EditField                  matlab.ui.control.EditField
        T5EditField                  matlab.ui.control.EditField
        GetT5T6Button                matlab.ui.control.Button
        T6EditFieldLabel             matlab.ui.control.Label
        T5EditFieldLabel             matlab.ui.control.Label
        Rain4UIAxes                  matlab.ui.control.UIAxes
        Height4UIAxes                matlab.ui.control.UIAxes
        HKGCCFitV6Tab                matlab.ui.container.Tab
        FitHbKappaGammaCknsCqinV6Button  matlab.ui.control.Button
        T8EditField                  matlab.ui.control.EditField
        T7EditField                  matlab.ui.control.EditField
        GetT7T8Button                matlab.ui.control.Button
        T7EditFieldLabel             matlab.ui.control.Label
        T8EditFieldLabel             matlab.ui.control.Label
        Rain5UIAxes                  matlab.ui.control.UIAxes
        Height5UIAxes                matlab.ui.control.UIAxes
        Dhdt5UIAxes                  matlab.ui.control.UIAxes
        PredictionbyHKGCCV5V6Tab     matlab.ui.container.Tab
        PredictHeightSignalManningButton  matlab.ui.control.Button
        T10EditField                 matlab.ui.control.EditField
        T9EditField                  matlab.ui.control.EditField
        GetT9T10Button               matlab.ui.control.Button
        T10EditFieldLabel            matlab.ui.control.Label
        T9EditFieldLabel             matlab.ui.control.Label
        Rain6UIAxes                  matlab.ui.control.UIAxes
        Height6UIAxes                matlab.ui.control.UIAxes
        SourceEditFieldLabel         matlab.ui.control.Label
    end

    properties (Access = private)
        ht_m % water leve (height) of all data
        rain_mm % rain data 
        t_idx % time index (1 2 3 ... ) in unit of minute
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % Importdatasinglefiletimeflowtwofiles12Button
        function Importdatasinglefiletimeflowtwofiles12ButtonPushed(app, event)
            %app.SourceEditField.Value = 'Great job';
            [source_file, source_dir, filterindex] = ...
                uigetfile({'*.csv','CSV-files (*.csv)'; ...
                           '*.*',  'All Files (*.*)'}, ...
                           'Pick rail-water csv file', ...
                           'MultiSelect', 'on');
            drawnow;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on'; 
            if (isscalar(source_file) && source_file == 0)
                return
            end
            % clear all text fields
            app.T1EditField.Value = '';
            app.T2EditField.Value = '';
            app.T3EditField.Value = '';
            app.T4EditField.Value = '';
            app.T5EditField.Value = '';
            app.T6EditField.Value = '';
            app.HbEditField.Value = '';
            app.LambdaEditField.Value = '';
            app.GammaEditField.Value = '';
            % clear all axes
            cla(app.HeightUIAxes);
            cla(app.RainUIAxes);
            cla(app.Height2UIAxes);
            cla(app.Dhdt2UIAxes);
            cla(app.Rain3UIAxes);
            cla(app.Height3UIAxes);
            cla(app.Dhdt3UIAxes);
            cla(app.Rain4UIAxes);
            cla(app.Height4UIAxes);
            % check which file is rain, which file is height
            % (assuming rain file has '雨量' in file name)
            % (assuming height file has '水位高度' in file name)
            filename1_strfind_rain = strfind(source_file{1}, '雨量');
            filename1_strfind_height = strfind(source_file{1}, '水位高度');
            filename2_strfind_rain = strfind(source_file{2}, '雨量');
            filename2_strfind_height = strfind(source_file{2}, '水位高度');
            filename1_has_rain = isscalar(filename1_strfind_rain) && filename1_strfind_rain > 0;
            filename1_has_height = isscalar(filename1_strfind_height) && filename1_strfind_height > 0;
            filename2_has_rain = isscalar(filename2_strfind_rain) && filename2_strfind_rain > 0;
            filename2_has_height = isscalar(filename2_strfind_height) && filename2_strfind_height > 0;
            % check if we need to switch two file names
            if (filename1_has_height || filename2_has_rain) 
                % switch two file names
                tmp = source_file{1};
                source_file{1} = source_file{2};
                source_file{2} = tmp;
            end
            % show source file
            %   if only one file is selected
            if (ischar(source_file))
                source_full_path = [source_dir source_file];
                app.SourceEditField.Value = source_full_path;
                % load data and show (plot) data
                filename = app.SourceEditField.Value;
                [app.t_idx, app.ht_m, app.rain_mm] = readHeightAndRain(filename);
                % show height and rain data on axes
                plotHeightAndRain2(app.t_idx, app.ht_m, app.rain_mm, app.HeightUIAxes, app.RainUIAxes);            
            end
            %   if exactly two files are selected
            if (iscell(source_file) && length(source_file) == 2)
                % the first file must be rain file, 
                %   1st column being time ("%m-%d-%Y ", e.g., 12/31/2023)
                %   2nd column being rainfall
                % the second file must be sewer measurement 
                %   1st column being time
                % only display the first selected file 
                app.SourceEditField.Value = [source_dir source_file{1}];
                % load data and show (plot) data
                [app.t_idx, app.ht_m, app.rain_mm] = readHeightAndRain_twoFiles(source_dir, source_file);
                % show height and rain data on axes
                plotHeightAndRain2(app.t_idx, app.ht_m, app.rain_mm, app.HeightUIAxes, app.RainUIAxes);            
            end

        end

        % Button pushed function: ShowsourceButton
        function ShowsourceButtonPushed(app, event)
            % read height and rain data from file 
            % (this button is temporarily disabled as we do not need it)
%            filename = app.SourceEditField.Value;
%            [app.t_idx, app.ht_m, app.rain_mm] = readHeightAndRain(filename);
%            % show height and rain data on axes
%            plotHeightAndRain2(app.t_idx, app.ht_m, app.rain_mm, app.HeightUIAxes, app.RainUIAxes);
        end

        % Button pushed function: GetT1T2Button
        function GetT1T2ButtonPushed(app, event)
            selected_xlim = xlim(app.RainUIAxes);
            t1 = int32(selected_xlim(1));
            t2 = int32(selected_xlim(2));
            t1 = max(t1, 1);
            t2 = min(t2, size(app.ht_m(:), 1)); 
            app.T1EditField.Value = sprintf('%d', t1);
            app.T2EditField.Value = sprintf('%d', t2);       
        end

        % Button pushed function: GetT3T4Button
        function GetT3T4ButtonPushed(app, event)
            selected_xlim = xlim(app.RainUIAxes);
            t3 = int32(selected_xlim(1));
            t4 = int32(selected_xlim(2));
            t3 = max(t3, 1);
            t4 = min(t4, size(app.ht_m(:), 1)); 
            app.T3EditField.Value = sprintf('%d', t3);
            app.T4EditField.Value = sprintf('%d', t4);                   
        end

        % Button pushed function: GetT5T6Button
        function GetT5T6ButtonPushed(app, event)
            selected_xlim = xlim(app.RainUIAxes);
            t5 = int32(selected_xlim(1));
            t6 = int32(selected_xlim(2));
            t5 = max(t5, 1);
            t6 = min(t6, size(app.ht_m(:), 1)); 
            app.T5EditField.Value = sprintf('%d', t5);
            app.T6EditField.Value = sprintf('%d', t6);                               
        end

        % Callback function: CloseTheAppButton, UIFigure
        function CloseTheAppButtonPushed(app, event)
            answer = questdlg('Are you sure you want to exit?', 'Confirm action', 'Yes', 'No', 'No');
            if strcmpi(answer, 'Yes')
                app.delete();
            else
                % Just do nothing
            end
        end

        % Button pushed function: CalculateLambdaButton
        function CalculateLambdaButtonPushed(app, event)
            % calculate lambda and other information
            t1 = eval(app.T1EditField.Value);
            t2 = eval(app.T2EditField.Value);
            [lambda, hb, dhdt_t12, lambda_corrcoef, ht_simu, t_hzero, p] ... 
                = calculateLambda(t1, t2, app.ht_m);
            % display hb and lambda on the widgets
            app.HbEditField.Value = sprintf('%.24f', hb);
            app.LambdaEditField.Value = sprintf('%.24f', lambda);
            % print information on the console
            fprintf('Lambda: %f\n', lambda); 
            fprintf('hb: %f\n', hb);
            fprintf('t_hzero: %f\n', t_hzero);
            % plot height (measured vs predicted)
            cla(app.Height2UIAxes); 
            t12 = double((t1:t2) - t1);
            ht_t12 = app.ht_m(t1:t2); ht_t12 = ht_t12(:);
            plot(app.Height2UIAxes, t12 + double(t1), ht_t12, 'b:', 'LineWidth', 2); 
            grid(app.Height2UIAxes, 'on');
            hold(app.Height2UIAxes, 'on');
            plot(app.Height2UIAxes, t12 + double(t1), ht_simu, 'r', 'LineWidth', 1); 
            text(app.Height2UIAxes, 0, 0, ...
                sprintf('dh/dt = %f T + %f\nhb = %f\nlambda = %f\nCorrCoef = %f\nT(ht=0) = %f', ...
                p(1), p(2), hb, lambda, lambda_corrcoef, t_hzero), ...
                'Interpreter', 'latex', ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'bottom');
            legend(app.Height2UIAxes, 'Measured', 'Predicted');
            xlim(app.Height2UIAxes, 'auto');
            axtoolbar(app.Height2UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.
            % plot dh/dt (measured vs predicted)
            cla(app.Dhdt2UIAxes); 
            plot(app.Dhdt2UIAxes, t12 + double(t1), dhdt_t12); 
            linkaxes([app.Height2UIAxes app.Dhdt2UIAxes], 'x');
            dhdt_linear_regression = polyval(p, t12); 
            hold(app.Dhdt2UIAxes, 'on');
            plot(app.Dhdt2UIAxes, t12 + double(t1), dhdt_linear_regression, 'linewidth', 2); 
            grid(app.Dhdt2UIAxes, 'on');
            xlabel(app.Dhdt2UIAxes, sprintf('Time (min) (starting from %d)', t1));
            xlim(app.Dhdt2UIAxes, 'auto');
            legend(app.Dhdt2UIAxes, 'Measured', 'Predicted');
            axtoolbar(app.Dhdt2UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
        end

        % Button pushed function: CalculateGammaButton
        function CalculateGammaButtonPushed(app, event)
            % calculate gamma and other information
            t3 = int32(eval(app.T3EditField.Value));
            t4 = int32(eval(app.T4EditField.Value));
            lambda = eval(app.LambdaEditField.Value);
            hb = eval(app.HbEditField.Value);
            h_min_1 = eval(app.H_dry_lowEditField.Value);
            h_min_2 = eval(app.H_dry_highEditField.Value);
            [gamma, ht_t34_predict, dhdt_t34, dhdt_t34_fitting] = ...
                calculateGamma(t3, t4, app.rain_mm, app.ht_m, lambda, hb, ...
                h_min_1, h_min_2);
            % display gamma on the widgets
            app.GammaEditField.Value = sprintf('%.24f', gamma);
            % print information on the screen
            fprintf('Gamma = %f\n', gamma);
            % plot rain
            cla(app.Rain3UIAxes);
            t34 = double((t3:t4) - t3); t34 = t34(:);
            ht_t34 = app.ht_m(t3:t4); ht_t34 = ht_t34(:);   
            rain_t34 = app.rain_mm(t3:t4); rain_t34 = rain_t34(:);
            plot(app.Rain3UIAxes, t34 + double(t3), rain_t34, 'b', 'LineWidth', 2); 
            grid(app.Rain3UIAxes, 'on');
            xlim(app.Rain3UIAxes, 'auto');
            axtoolbar(app.Rain3UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            % plot heights (measured vs predicted)
            cla(app.Height3UIAxes);
            plot(app.Height3UIAxes, t34 + double(t3), ht_t34, 'b:', 'LineWidth', 2); 
            hold(app.Height3UIAxes, 'on');
            plot(app.Height3UIAxes, t34 + double(t3), ht_t34_predict, 'r', 'LineWidth', 1); 
            grid(app.Height3UIAxes, 'on');
            legend(app.Height3UIAxes, 'Measured', 'Fitting for gamma');
            axtoolbar(app.Height3UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Height3UIAxes, 'auto');
            % plot dh/dt (measured vs predicted)
            cla(app.Dhdt3UIAxes);
            plot(app.Dhdt3UIAxes, t34 + double(t3), dhdt_t34, 'b:', 'LineWidth', 2);
            hold(app.Dhdt3UIAxes, 'on');
            plot(app.Dhdt3UIAxes, t34 + double(t3), dhdt_t34_fitting, 'r', 'LineWidth', 1); 
            xlabel(app.Dhdt3UIAxes, sprintf('Time (min) (starting from %d)', t3));
            legend(app.Dhdt3UIAxes, 'Measured', 'Predicted');
            grid(app.Dhdt3UIAxes, 'on');
            axtoolbar(app.Dhdt3UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Dhdt3UIAxes, 'auto');
            linkaxes([app.Rain3UIAxes app.Height3UIAxes app.Dhdt3UIAxes], 'x');
        end

        % Button pushed function: PredictheightButton
        function PredictheightButtonPushed(app, event)
            % predict 
            t5 = int32(eval(app.T5EditField.Value));
            t6 = int32(eval(app.T6EditField.Value));
            lambda = eval(app.LambdaEditField.Value);
            hb = eval(app.HbEditField.Value);
            gamma = eval(app.GammaEditField.Value);
            % forward calculation 
            %[ht_t56_predict] = predictHeight(t5, t6, ...
            %    app.rain_mm, app.ht_m, lambda, hb, gamma);
            % ode23 calculation
            [ht_t56_predict] = predictHeight_ode23(t5, t6, ...
                app.rain_mm, app.ht_m, lambda, hb, gamma);
            % plot rain 
            cla(app.Rain4UIAxes);
            t56 = double((t5:t6) - t5); t56 = t56(:);
            ht_t56 = app.ht_m(t5:t6); ht_t56 = ht_t56(:);   
            rain_t56 = app.rain_mm(t5:t6); rain_t56 = rain_t56(:);
            plot(app.Rain4UIAxes, t56 + double(t5), rain_t56, 'b', 'LineWidth', 2); 
            grid(app.Rain4UIAxes, 'on');
            axtoolbar(app.Rain4UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Rain4UIAxes, 'auto');
            % plot heights (measured vs predicted)
            cla(app.Height4UIAxes);
            plot(app.Height4UIAxes, t56 + double(t5), ht_t56, 'b:', 'LineWidth', 2); 
            hold(app.Height4UIAxes, 'on');
            plot(app.Height4UIAxes, t56 + double(t5), ht_t56_predict, 'r', 'LineWidth', 1); 
            grid(app.Height4UIAxes, 'on');
            legend(app.Height4UIAxes, 'Measured', 'Predicted');
            axtoolbar(app.Height4UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Height4UIAxes, 'auto');
            linkaxes([app.Rain4UIAxes app.Height4UIAxes ], 'x');
        end

        % Button pushed function: EstimateH_minButton
        function EstimateH_minButtonPushed(app, event)
            h_min_1 = prctile(app.ht_m, 50);
            h_min_2 = prctile(app.ht_m, 85);
            app.H_dry_lowEditField.Value = sprintf('%f', h_min_1);
            app.H_dry_highEditField.Value = sprintf('%f', h_min_2);
        end

        % Button pushed function: GetT7T8Button
        function GetT7T8ButtonPushed(app, event)
            selected_xlim = xlim(app.RainUIAxes);
            t7 = int32(selected_xlim(1));
            t8 = int32(selected_xlim(2));
            t7 = max(t7, 1);
            t8 = min(t8, size(app.ht_m(:), 1)); 
            app.T7EditField.Value = sprintf('%d', t7);
            app.T8EditField.Value = sprintf('%d', t8);                   
        end

        % Button pushed function: FitHbKappaGammaCknsCqinV6Button
        function FitHbKappaGammaCknsCqinV6ButtonPushed(app, event)
            try
                t7 = eval(app.T7EditField.Value);
                t8 = eval(app.T8EditField.Value);
                diameter = eval(app.DiameterEditField.Value);
            catch
                error('T7, T8, diameter need to be defined.')
            end
            % get initial values if they are in edit boxes
            hb_init = -1.0;
            kappa_init = 0.001; 
            gamma_init = 0.001;
            ckns_init = 0.001;
            cqin_init = 0.001;
            if isnan(str2double(app.HbEditField.Value)) == false
                hb_init = str2double(app.HbEditField.Value);
            end
            if isnan(str2double(app.KappaEditField.Value)) == false
                kappa_init = str2double(app.KappaEditField.Value);
            end
            if isnan(str2double(app.GammaEditField.Value)) == false
                gamma_init = str2double(app.GammaEditField.Value);
            end
            if isnan(str2double(app.ckns05EditField.Value)) == false
                ckns_init = str2double(app.ckns05EditField.Value);
            end
            if isnan(str2double(app.cqinEditField.Value)) == false
                cqin_init = str2double(app.cqinEditField.Value);
            end
            % optimize h_b, lambda, gamma, ckns, cqin
            [hb, kappa, gamma, ckns, cqin, ...
                ht_sub_predict, dhdt_sub_diff, dhdt_sub_predict] = ...
                calculate_v6_qin_qout_params(...
                app.ht_m(t7:t8), app.rain_mm(t7:t8), diameter, ...
                hb_init, kappa_init, gamma_init, ckns_init, cqin_init);

            % display h_b, lambda, gamma, knss on the widgets
            app.HbEditField.Value = sprintf('%.24f', hb);
            app.KappaEditField.Value = sprintf('%.24f', kappa);
            app.GammaEditField.Value = sprintf('%.24f', gamma);
            app.ckns05EditField.Value = sprintf('%.24f', ckns);
            app.cqinEditField.Value = sprintf('%.24f', cqin);
            % plot rain
            cla(app.Rain5UIAxes);
            plot(app.Rain5UIAxes, t7:t8, app.rain_mm(t7:t8), 'b', 'LineWidth', 2); 
            grid(app.Rain5UIAxes, 'on');
            xlim(app.Rain5UIAxes, 'auto');
            axtoolbar(app.Rain5UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            % plot heights (measured vs predicted)
            cla(app.Height5UIAxes);
            plot(app.Height5UIAxes, t7:t8, app.ht_m(t7:t8), 'b:', 'LineWidth', 2); 
            hold(app.Height5UIAxes, 'on');
            plot(app.Height5UIAxes, t7:t8, ht_sub_predict, 'r', 'LineWidth', 1); 
            grid(app.Height5UIAxes, 'on');
            legend(app.Height5UIAxes, 'Measured', 'Predicted');
            axtoolbar(app.Height5UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Height5UIAxes, 'auto');
            % plot dh/dt (measured vs predicted)
            cla(app.Dhdt5UIAxes);
            plot(app.Dhdt5UIAxes, t7:t8, dhdt_sub_diff, 'b:', 'LineWidth', 2);
            hold(app.Dhdt5UIAxes, 'on');
            plot(app.Dhdt5UIAxes, t7:t8, dhdt_sub_predict, 'r', 'LineWidth', 1); 
            xlabel(app.Dhdt5UIAxes, sprintf('Time (min) (starting from %d)', t7));
            legend(app.Dhdt5UIAxes, 'Measured', 'Predicted');
            grid(app.Dhdt5UIAxes, 'on');
            axtoolbar(app.Dhdt5UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Dhdt5UIAxes, 'auto');
            linkaxes([app.Rain5UIAxes app.Height5UIAxes app.Dhdt5UIAxes], 'x');
        end

        % Button pushed function: GetT9T10Button
        function GetT9T10ButtonPushed(app, event)
            selected_xlim = xlim(app.RainUIAxes);
            t9 = int32(selected_xlim(1));
            t10 = int32(selected_xlim(2));
            t9 = max(t9, 1);
            t10 = min(t10, size(app.ht_m(:), 1)); 
            app.T9EditField.Value = sprintf('%d', t9);
            app.T10EditField.Value = sprintf('%d', t10);                                           
        end

        % Button pushed function: PredictHeightSignalManningButton
        function PredictHeightSignalManningButtonPushed(app, event)
            % predict 
            t9 = int32(eval(app.T9EditField.Value));
            t10 = int32(eval(app.T10EditField.Value));
            diameter = eval(app.DiameterEditField.Value);
            hb = eval(app.HbEditField.Value);
            kappa = eval(app.KappaEditField.Value);
            gamma = eval(app.GammaEditField.Value);
            ckns = eval(app.ckns05EditField.Value);
            cqin = eval(app.cqinEditField.Value);
            % ode23 calculation
            rain_mm_sub = app.rain_mm(t9:t10);
            ht_init = app.ht_m(t9);
            ht_t910_predict = ...
                predictHeight_v5_qin_qout( ...
                ht_init, rain_mm_sub, ...
                diameter, hb, kappa, gamma, ckns, cqin);            
            
            % plot rain 
            cla(app.Rain6UIAxes);
            t910 = double((t9:t10) - t9); t910 = t910(:);
            ht_t910 = app.ht_m(t9:t10); ht_t910 = ht_t910(:);   
            rain_t910 = app.rain_mm(t9:t10); rain_t910 = rain_t910(:);
            plot(app.Rain6UIAxes, t910 + double(t9), rain_t910, 'b', 'LineWidth', 2); 
            grid(app.Rain6UIAxes, 'on');
            axtoolbar(app.Rain6UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Rain6UIAxes, 'auto');
            % plot heights (measured vs predicted)
            cla(app.Height6UIAxes);
            plot(app.Height6UIAxes, t910 + double(t9), ht_t910, 'b:', 'LineWidth', 2); 
            hold(app.Height6UIAxes, 'on');
            plot(app.Height6UIAxes, t910 + double(t9), ht_t910_predict, 'r', 'LineWidth', 1); 
            grid(app.Height6UIAxes, 'on');
            legend(app.Height6UIAxes, 'Measured', 'Predicted');
            axtoolbar(app.Height6UIAxes,{'export','brush','datacursor','pan','zoomin','zoomout','restoreview'}); % enable ax toolbar after legend hiding it.            
            xlim(app.Height6UIAxes, 'auto');
            linkaxes([app.Rain6UIAxes app.Height6UIAxes ], 'x');
        end

        % Button pushed function: V7ImportdataC1TC2HC4RButton
        function V7ImportdataC1TC2HC4RButtonPushed(app, event)
            [source_file, source_dir, filterindex] = ...
                uigetfile({'*.csv','CSV-files (*.csv)'; ...
                           '*.*',  'All Files (*.*)'}, ...
                           'Pick CSV file (C1:Time, C2:Height, C3:Ignored, C4:Rain)', ...
                           'MultiSelect', 'off');
            drawnow;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on'; 
            if (isscalar(source_file) && source_file == 0)
                return
            end
            % clear all text fields
            app.T1EditField.Value = '';
            app.T2EditField.Value = '';
            app.T3EditField.Value = '';
            app.T4EditField.Value = '';
            app.T5EditField.Value = '';
            app.T6EditField.Value = '';
            app.HbEditField.Value = '';
            app.LambdaEditField.Value = '';
            app.GammaEditField.Value = '';
            % clear all axes
            cla(app.HeightUIAxes);
            cla(app.RainUIAxes);
            cla(app.Height2UIAxes);
            cla(app.Dhdt2UIAxes);
            cla(app.Rain3UIAxes);
            cla(app.Height3UIAxes);
            cla(app.Dhdt3UIAxes);
            cla(app.Rain4UIAxes);
            cla(app.Height4UIAxes);
            % show source file (V7, 4-column CSV, C1:Time, C2:Height,
            % C3:Station(ignored), C4:Rain
            source_full_path = [source_dir source_file];
            app.SourceEditField.Value = source_full_path;
            % load data and show (plot) data
            filename = app.SourceEditField.Value;
            [app.t_idx, app.ht_m, app.rain_mm] = readHeightAndRain_smartiout_v7(filename);
            % show height and rain data on axes
            plotHeightAndRain2(app.t_idx, app.ht_m, app.rain_mm, app.HeightUIAxes, app.RainUIAxes);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseTheAppButtonPushed, true);
            app.SourceEditFieldLabel = uilabel(app.UIFigure);
            app.SourceEditFieldLabel.Text = 'Source:';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaterlevelandrainTab = uitab(app.TabGroup);
            app.WaterlevelandrainTab.Title = 'Water level and rain';
            app.HeightUIAxes = uiaxes(app.WaterlevelandrainTab);
            title(app.HeightUIAxes, 'Height')
            xlabel(app.HeightUIAxes, 'X')
            ylabel(app.HeightUIAxes, 'Y')
            zlabel(app.HeightUIAxes, 'Z')
            app.RainUIAxes = uiaxes(app.WaterlevelandrainTab);
            title(app.RainUIAxes, 'Rain')
            xlabel(app.RainUIAxes, 'X')
            ylabel(app.RainUIAxes, 'Y')
            zlabel(app.RainUIAxes, 'Z')
            app.LambdacalculationTab = uitab(app.TabGroup);
            app.LambdacalculationTab.Title = 'Lambda calculation';
            app.Dhdt2UIAxes = uiaxes(app.LambdacalculationTab);
            title(app.Dhdt2UIAxes, 'dH/dt')
            xlabel(app.Dhdt2UIAxes, 'X')
            ylabel(app.Dhdt2UIAxes, 'dH/dt (m/min)')
            zlabel(app.Dhdt2UIAxes, 'Z')
            app.Height2UIAxes = uiaxes(app.LambdacalculationTab);
            title(app.Height2UIAxes, 'Height (water level) (m)')
            xlabel(app.Height2UIAxes, 'X')
            ylabel(app.Height2UIAxes, 'Height (water level) m')
            zlabel(app.Height2UIAxes, 'Z')
            app.T1EditFieldLabel = uilabel(app.LambdacalculationTab);
            app.T1EditFieldLabel.Text = 'T1';
            app.T2EditFieldLabel = uilabel(app.LambdacalculationTab);
            app.T2EditFieldLabel.Text = 'T2';
            app.GetT1T2Button = uibutton(app.LambdacalculationTab, 'push');
            app.GetT1T2Button.ButtonPushedFcn = createCallbackFcn(app, @GetT1T2ButtonPushed, true);
            app.GetT1T2Button.Text = 'Get T1 & T2';
            app.T1EditField = uieditfield(app.LambdacalculationTab, 'text');
            app.T2EditField = uieditfield(app.LambdacalculationTab, 'text');
            app.CalculateLambdaButton = uibutton(app.LambdacalculationTab, 'push');
            app.CalculateLambdaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateLambdaButtonPushed, true);
            app.CalculateLambdaButton.Text = 'Calculate Lambda';
            app.GammacalculationTab = uitab(app.TabGroup);
            app.GammacalculationTab.Title = 'Gamma calculation';
            app.Dhdt3UIAxes = uiaxes(app.GammacalculationTab);
            title(app.Dhdt3UIAxes, 'dh/dt (mm/min)')
            xlabel(app.Dhdt3UIAxes, 'X')
            ylabel(app.Dhdt3UIAxes, 'Y')
            zlabel(app.Dhdt3UIAxes, 'Z')
            app.Height3UIAxes = uiaxes(app.GammacalculationTab);
            title(app.Height3UIAxes, 'Height (water level) (mm)')
            xlabel(app.Height3UIAxes, 'X')
            ylabel(app.Height3UIAxes, 'Y')
            zlabel(app.Height3UIAxes, 'Z')
            app.Rain3UIAxes = uiaxes(app.GammacalculationTab);
            title(app.Rain3UIAxes, 'Rainfall (mm/min)')
            xlabel(app.Rain3UIAxes, 'X')
            ylabel(app.Rain3UIAxes, 'Y')
            zlabel(app.Rain3UIAxes, 'Z')
            app.T4EditFieldLabel = uilabel(app.GammacalculationTab);
            app.T4EditFieldLabel.Text = 'T4';
            app.T3Label = uilabel(app.GammacalculationTab);
            app.T3Label.Text = 'T3';
            app.H_dry_lowEditFieldLabel = uilabel(app.GammacalculationTab);
            app.H_dry_lowEditFieldLabel.Text = 'H_dry_low';
            app.H_dry_highEditFieldLabel = uilabel(app.GammacalculationTab);
            app.H_dry_highEditFieldLabel.Text = 'H_dry_high';
            app.GetT3T4Button = uibutton(app.GammacalculationTab, 'push');
            app.GetT3T4Button.ButtonPushedFcn = createCallbackFcn(app, @GetT3T4ButtonPushed, true);
            app.GetT3T4Button.Text = 'Get T3 & T4';
            app.T3EditField = uieditfield(app.GammacalculationTab, 'text');
            app.T4EditField = uieditfield(app.GammacalculationTab, 'text');
            app.EstimateH_minButton = uibutton(app.GammacalculationTab, 'push');
            app.EstimateH_minButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateH_minButtonPushed, true);
            app.EstimateH_minButton.Text = 'Estimate H_min';
            app.H_dry_lowEditField = uieditfield(app.GammacalculationTab, 'text');
            app.H_dry_highEditField = uieditfield(app.GammacalculationTab, 'text');
            app.CalculateGammaButton = uibutton(app.GammacalculationTab, 'push');
            app.CalculateGammaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateGammaButtonPushed, true);
            app.CalculateGammaButton.Text = 'Calculate Gamma';
            app.PredictionTab = uitab(app.TabGroup);
            app.PredictionTab.Title = 'Prediction';
            app.Height4UIAxes = uiaxes(app.PredictionTab);
            title(app.Height4UIAxes, 'Height (water level) (mm)')
            xlabel(app.Height4UIAxes, 'X')
            ylabel(app.Height4UIAxes, 'Y')
            zlabel(app.Height4UIAxes, 'Z')
            app.Rain4UIAxes = uiaxes(app.PredictionTab);
            title(app.Rain4UIAxes, 'Rainfall (mm/min)')
            xlabel(app.Rain4UIAxes, 'X')
            ylabel(app.Rain4UIAxes, 'Y')
            zlabel(app.Rain4UIAxes, 'Z')
            app.T5EditFieldLabel = uilabel(app.PredictionTab);
            app.T5EditFieldLabel.Text = 'T5';
            app.T6EditFieldLabel = uilabel(app.PredictionTab);
            app.T6EditFieldLabel.Text = 'T6';
            app.GetT5T6Button = uibutton(app.PredictionTab, 'push');
            app.GetT5T6Button.ButtonPushedFcn = createCallbackFcn(app, @GetT5T6ButtonPushed, true);
            app.GetT5T6Button.Text = 'Get T5 & T6';
            app.T5EditField = uieditfield(app.PredictionTab, 'text');
            app.T6EditField = uieditfield(app.PredictionTab, 'text');
            app.PredictheightButton = uibutton(app.PredictionTab, 'push');
            app.PredictheightButton.ButtonPushedFcn = createCallbackFcn(app, @PredictheightButtonPushed, true);
            app.PredictheightButton.Text = 'Predict height';
            app.HKGCCFitV6Tab = uitab(app.TabGroup);
            app.HKGCCFitV6Tab.Title = 'HKGCC Fit (V6)';
            app.Dhdt5UIAxes = uiaxes(app.HKGCCFitV6Tab);
            title(app.Dhdt5UIAxes, 'dh/dt (mm/min)')
            xlabel(app.Dhdt5UIAxes, 'X')
            ylabel(app.Dhdt5UIAxes, 'Y')
            zlabel(app.Dhdt5UIAxes, 'Z')
            app.Height5UIAxes = uiaxes(app.HKGCCFitV6Tab);
            title(app.Height5UIAxes, 'Height (water level) (mm)')
            xlabel(app.Height5UIAxes, 'X')
            ylabel(app.Height5UIAxes, 'Y')
            zlabel(app.Height5UIAxes, 'Z')
            app.Rain5UIAxes = uiaxes(app.HKGCCFitV6Tab);
            title(app.Rain5UIAxes, 'Rainfall (mm/min)')
            xlabel(app.Rain5UIAxes, 'X')
            ylabel(app.Rain5UIAxes, 'Y')
            zlabel(app.Rain5UIAxes, 'Z')
            app.T8EditFieldLabel = uilabel(app.HKGCCFitV6Tab);
            app.T8EditFieldLabel.Text = 'T8';
            app.T7EditFieldLabel = uilabel(app.HKGCCFitV6Tab);
            app.T7EditFieldLabel.Text = 'T7';
            app.GetT7T8Button = uibutton(app.HKGCCFitV6Tab, 'push');
            app.GetT7T8Button.ButtonPushedFcn = createCallbackFcn(app, @GetT7T8ButtonPushed, true);
            app.GetT7T8Button.Text = 'Get T7 & T8';
            app.T7EditField = uieditfield(app.HKGCCFitV6Tab, 'text');
            app.T8EditField = uieditfield(app.HKGCCFitV6Tab, 'text');
            app.FitHbKappaGammaCknsCqinV6Button = uibutton(app.HKGCCFitV6Tab, 'push');
            app.FitHbKappaGammaCknsCqinV6Button.ButtonPushedFcn = createCallbackFcn(app, @FitHbKappaGammaCknsCqinV6ButtonPushed, true);
            app.FitHbKappaGammaCknsCqinV6Button.Text = 'Fit Hb Kappa Gamma Ckns Cqin (V6)';
            app.PredictionbyHKGCCV5V6Tab = uitab(app.TabGroup);
            app.PredictionbyHKGCCV5V6Tab.Title = 'Prediction by HKGCC (V5/V6)';
            app.Height6UIAxes = uiaxes(app.PredictionbyHKGCCV5V6Tab);
            title(app.Height6UIAxes, 'Height (water level) (mm)')
            xlabel(app.Height6UIAxes, 'X')
            ylabel(app.Height6UIAxes, 'Y')
            zlabel(app.Height6UIAxes, 'Z')
            app.Rain6UIAxes = uiaxes(app.PredictionbyHKGCCV5V6Tab);
            title(app.Rain6UIAxes, 'Rainfall (mm/min)')
            xlabel(app.Rain6UIAxes, 'X')
            ylabel(app.Rain6UIAxes, 'Y')
            zlabel(app.Rain6UIAxes, 'Z')
            app.T9EditFieldLabel = uilabel(app.PredictionbyHKGCCV5V6Tab);
            app.T9EditFieldLabel.Text = 'T9';
            app.T10EditFieldLabel = uilabel(app.PredictionbyHKGCCV5V6Tab);
            app.T10EditFieldLabel.Text = 'T10';
            app.GetT9T10Button = uibutton(app.PredictionbyHKGCCV5V6Tab, 'push');
            app.GetT9T10Button.ButtonPushedFcn = createCallbackFcn(app, @GetT9T10ButtonPushed, true);
            app.GetT9T10Button.Text = 'Get T9 & T10';
            app.T9EditField = uieditfield(app.PredictionbyHKGCCV5V6Tab, 'text');
            app.T10EditField = uieditfield(app.PredictionbyHKGCCV5V6Tab, 'text');
            app.PredictHeightSignalManningButton = uibutton(app.PredictionbyHKGCCV5V6Tab, 'push');
            app.PredictHeightSignalManningButton.ButtonPushedFcn = createCallbackFcn(app, @PredictHeightSignalManningButtonPushed, true);
            app.PredictHeightSignalManningButton.Text = 'Predict height (Signal Manning)';
            app.LambdaEditFieldLabel = uilabel(app.UIFigure);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.HbEditFieldLabel = uilabel(app.UIFigure);
            app.HbEditFieldLabel.Text = 'Hb';
            app.cknss05EditFieldLabel = uilabel(app.UIFigure);
            app.cknss05EditFieldLabel.Text = 'c.k/n*ss^0.5';
            app.DiameterEditFieldLabel = uilabel(app.UIFigure);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.cqinEditFieldLabel = uilabel(app.UIFigure);
            app.cqinEditFieldLabel.Text = 'cqin';
            app.KappaEditFieldLabel = uilabel(app.UIFigure);
            app.KappaEditFieldLabel.Text = 'Kappa';
            app.Importdatasinglefiletimeflowtwofiles12Button = uibutton(app.UIFigure, 'push');
            app.Importdatasinglefiletimeflowtwofiles12Button.ButtonPushedFcn = createCallbackFcn(app, @Importdatasinglefiletimeflowtwofiles12ButtonPushed, true);
            app.Importdatasinglefiletimeflowtwofiles12Button.Text = {'V6 Import data (single file: time/flow/height/rain. '; 'two files: 1: time/rain  2: time/height)'};
            app.SourceEditField = uieditfield(app.UIFigure, 'text');
            app.ShowsourceButton = uibutton(app.UIFigure, 'push');
            app.ShowsourceButton.ButtonPushedFcn = createCallbackFcn(app, @ShowsourceButtonPushed, true);
            app.ShowsourceButton.Enable = 'off';
            app.ShowsourceButton.Text = 'Show source';
            app.CloseTheAppButton = uibutton(app.UIFigure, 'push');
            app.CloseTheAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseTheAppButtonPushed, true);
            app.CloseTheAppButton.Text = 'Close The App';
            app.DiameterEditField = uieditfield(app.UIFigure, 'text');
            app.DiameterEditField.Value = '0.6';
            app.HbEditField = uieditfield(app.UIFigure, 'text');
            app.LambdaEditField = uieditfield(app.UIFigure, 'text');
            app.KappaEditField = uieditfield(app.UIFigure, 'text');
            app.GammaEditField = uieditfield(app.UIFigure, 'text');
            app.ckns05EditField = uieditfield(app.UIFigure, 'text');
            app.cqinEditField = uieditfield(app.UIFigure, 'text');
            app.V7ImportdataC1TC2HC4RButton = uibutton(app.UIFigure, 'push');
            app.V7ImportdataC1TC2HC4RButton.ButtonPushedFcn = createCallbackFcn(app, @V7ImportdataC1TC2HC4RButtonPushed, true);
            app.V7ImportdataC1TC2HC4RButton.Text = {'V7 Import data'; '(C1.T.C2.H.C4.R)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end